classdef polyspiral < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PolynomialspiralsUIFigure  matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        aSliderLabel               matlab.ui.control.Label
        aSlider                    matlab.ui.control.Slider
        Label                      matlab.ui.control.Label
        bSliderLabel               matlab.ui.control.Label
        bSlider                    matlab.ui.control.Slider
        cSliderLabel               matlab.ui.control.Label
        cSlider                    matlab.ui.control.Slider
        dSliderLabel               matlab.ui.control.Label
        dSlider                    matlab.ui.control.Slider
        s_fSliderLabel             matlab.ui.control.Label
        s_fSlider                  matlab.ui.control.Slider
        psi0SliderLabel            matlab.ui.control.Label
        psi0Slider                 matlab.ui.control.Slider
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            parameterChanged(app);
        end

        % Value changed function: aSlider, bSlider, cSlider, dSlider, 
        % psi0Slider, s_fSlider
        function parameterChanged(app, event)
            psi0 = app.psi0Slider.Value;
            a = app.aSlider.Value;
            b = app.bSlider.Value;
            c = app.cSlider.Value;
            d = app.dSlider.Value;
            sf = app.s_fSlider.Value;
            
            func = @(s)(psi0+a*s+b/2*s.^2+c/3*s.^3+d/4*s.^4)
            s = 0:0.005:sf;
            len = length(s);
            x = zeros(1, len);
            y = zeros(1, len);
            
            s1 = @(s)(sin(func(s)));
            s2 = @(s)(cos(func(s)));
            
            %evaluation of fresnel integral
            % rather than integrate from 0 to s, integrate over just the last bit
            for n = 2:len
                x(n) = x(n-1)+ integral( s1, s(n-1), s(n));
                y(n) = y(n-1)+ integral( s2, s(n-1), s(n));
            end
            
            % plot it in the window
            cla(app.UIAxes)
            plot(app.UIAxes, y,x,'LineWidth',2);
            daspect(app.UIAxes, [1 1 1]);
            grid(app.UIAxes, 'on')
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, y(1), x(1), 'o')
            hold(app.UIAxes, 'off')
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PolynomialspiralsUIFigure = uifigure;
            app.PolynomialspiralsUIFigure.Name = 'Polynomial spirals';
            app.UIAxes = uiaxes(app.PolynomialspiralsUIFigure);
            title(app.UIAxes, 'k(s)=a+bs+cs^2+ds^3')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.aSliderLabel = uilabel(app.PolynomialspiralsUIFigure);
            app.aSliderLabel.Text = 'a';
            app.aSlider = uislider(app.PolynomialspiralsUIFigure);
            app.aSlider.Limits = [-10 10];
            app.aSlider.MajorTicks = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.aSlider.ValueChangedFcn = createCallbackFcn(app, @parameterChanged, true);
            app.Label = uilabel(app.PolynomialspiralsUIFigure);
            app.Label.Text = '';
            app.bSliderLabel = uilabel(app.PolynomialspiralsUIFigure);
            app.bSliderLabel.Text = 'b';
            app.bSlider = uislider(app.PolynomialspiralsUIFigure);
            app.bSlider.Limits = [-2 2];
            app.bSlider.ValueChangedFcn = createCallbackFcn(app, @parameterChanged, true);
            app.cSliderLabel = uilabel(app.PolynomialspiralsUIFigure);
            app.cSliderLabel.Text = 'c';
            app.cSlider = uislider(app.PolynomialspiralsUIFigure);
            app.cSlider.Limits = [-2 2];
            app.cSlider.ValueChangedFcn = createCallbackFcn(app, @parameterChanged, true);
            app.dSliderLabel = uilabel(app.PolynomialspiralsUIFigure);
            app.dSliderLabel.Text = 'd';
            app.dSlider = uislider(app.PolynomialspiralsUIFigure);
            app.dSlider.Limits = [-2 2];
            app.dSlider.ValueChangedFcn = createCallbackFcn(app, @parameterChanged, true);
            app.s_fSliderLabel = uilabel(app.PolynomialspiralsUIFigure);
            app.s_fSliderLabel.Text = 's_f';
            app.s_fSlider = uislider(app.PolynomialspiralsUIFigure);
            app.s_fSlider.Limits = [0 10];
            app.s_fSlider.ValueChangedFcn = createCallbackFcn(app, @parameterChanged, true);
            app.s_fSlider.Value = 5;
            app.psi0SliderLabel = uilabel(app.PolynomialspiralsUIFigure);
            app.psi0SliderLabel.Text = 'psi0';
            app.psi0Slider = uislider(app.PolynomialspiralsUIFigure);
            app.psi0Slider.Limits = [-4 4];
            app.psi0Slider.ValueChangedFcn = createCallbackFcn(app, @parameterChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = polyspiral

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PolynomialspiralsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PolynomialspiralsUIFigure)
        end
    end
end